classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        BasicTab                        matlab.ui.container.Tab
        StudyNameEditFieldLabel         matlab.ui.control.Label
        studyName                       matlab.ui.control.EditField
        SaveStagesLabel                 matlab.ui.control.Label
        savepoint                       matlab.ui.control.ListBox
        OutputdirectoryButton           matlab.ui.control.Button
        DatadirectoryButton             matlab.ui.control.Button
        dataDir                         matlab.ui.control.EditField
        outputDir                       matlab.ui.control.EditField
        DataprotocolDropDownLabel       matlab.ui.control.Label
        dataprotocol                    matlab.ui.control.DropDown
        RunStagesLabel                  matlab.ui.control.Label
        runSteps                        matlab.ui.control.ListBox
        PlotStagesLabel                 matlab.ui.control.Label
        plotsave                        matlab.ui.control.ListBox
        IfDatasamplingrateisaboveLabel  matlab.ui.control.Label
        fromSampling                    matlab.ui.control.NumericEditField
        downsampletoLabel               matlab.ui.control.Label
        toSampling                      matlab.ui.control.NumericEditField
        AutomaticrejectSwitch           matlab.ui.control.Switch
        AutomaticrejectSwitchLabel      matlab.ui.control.Label
        WARNINGmanipulatingindividualsubjectdatadurationLabel  matlab.ui.control.Label
        singlesubject                   matlab.ui.control.Switch
        TestsinglesubjectLabel          matlab.ui.control.Label
        ChannelsTab                     matlab.ui.container.Tab
        runChannels                     matlab.ui.control.ListBox
        NumChannelsLabel                matlab.ui.control.Label
        numChannels                     matlab.ui.control.Slider
        RunChannelsLabel                matlab.ui.control.Label
        ChannelfileButton               matlab.ui.control.Button
        channelFile                     matlab.ui.control.EditField
        PrepTab                         matlab.ui.container.Tab
        DetrendCutoffHzSpinnerLabel     matlab.ui.control.Label
        detrendCutoff                   matlab.ui.control.Spinner
        DetrendStepSizeSpinnerLabel     matlab.ui.control.Label
        detrendStepSize                 matlab.ui.control.Spinner
        LinefrequenciesmultiplechoiceListBoxLabel  matlab.ui.control.Label
        lineFrequencies                 matlab.ui.control.ListBox
        EUILLabel                       matlab.ui.control.Label
        USLabel                         matlab.ui.control.Label
        HighpassFilterHzSpinnerLabel    matlab.ui.control.Label
        highpass                        matlab.ui.control.Spinner
        LowpassFilterHzSpinnerLabel     matlab.ui.control.Label
        lowpass                         matlab.ui.control.Spinner
        NotchLowFilterHzLabel           matlab.ui.control.Label
        notchlow                        matlab.ui.control.Spinner
        HighpassHighHzLabel             matlab.ui.control.Label
        notchhigh                       matlab.ui.control.Spinner
        revfiltLabel                    matlab.ui.control.Label
        revfilt                         matlab.ui.control.Spinner
        ICATab                          matlab.ui.container.Tab
        RobustDeviationThresholdSpinnerLabel  matlab.ui.control.Label
        robustDeviationThreshold        matlab.ui.control.Spinner
        CorrelationThresholdSpinnerLabel  matlab.ui.control.Label
        correlationThreshold            matlab.ui.control.Spinner
        BadTimeThresholdSpinnerLabel    matlab.ui.control.Label
        badTimeThreshold                matlab.ui.control.Spinner
        HighFrequencyNoiseThresholdSpinnerLabel  matlab.ui.control.Label
        highFrequencyNoiseThreshold     matlab.ui.control.Spinner
        RansacCorrelationThresholdSpinnerLabel  matlab.ui.control.Label
        ransacCorrelationThreshold      matlab.ui.control.Spinner
        ICLabelTab                      matlab.ui.container.Tab
        BrainSliderLabel                matlab.ui.control.Label
        brain                           matlab.ui.control.Slider
        muscle                          matlab.ui.control.Slider
        eye                             matlab.ui.control.Slider
        heart                           matlab.ui.control.Slider
        line                            matlab.ui.control.Slider
        channel                         matlab.ui.control.Slider
        other                           matlab.ui.control.Slider
        MuscleLabel                     matlab.ui.control.Label
        EyeLabel                        matlab.ui.control.Label
        HeartLabel                      matlab.ui.control.Label
        LineLabel                       matlab.ui.control.Label
        ChannelLabel                    matlab.ui.control.Label
        OtherLabel                      matlab.ui.control.Label
        ResetValuesButton               matlab.ui.control.StateButton
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, startup)
            ResetValuesButtonValueChanged(app);
        end

        % Button pushed function: OutputdirectoryButton
        function OutputdirectoryButtonPushed(app, event)
            outputDirectory = uigetdir;
            if (~(outputDirectory == 0))
               app.outputDir.Value = outputDirectory;
            end
        end

        % Button pushed function: DatadirectoryButton
        function DatadirectoryButtonPushed(app, event)
            dataDirectory = uigetdir;
            if (~(dataDirectory == 0))
               app.dataDir.Value = dataDirectory;    
            end
        end

        % Button pushed function: ChannelfileButton
        function ChannelfileButtonPushed(app, event)
            channelFilePath = uigetfile;
            if (~(channelFilePath == 0))
                app.channelFile.Value = channelFilePath;    
            end
        end

        % Value changed function: RunButton
        function RunApp(app, event)
            keysConf = {'studyName', 'dataprotocol', 'singlesubject', 'plotsave', 'dataDir', 'channelFile', 'outputDir', 'runSteps', 'savepoint', 'lineFrequencies', ...
                    'lowpass', 'highpass','notchlow','notchhigh','revfilt', 'detrendCutoff', 'detrendStepSize', 'robustDeviationThreshold' ...
                    'correlationThreshold', 'badTimeThreshold', 'highFrequencyNoiseThreshold', 'ransacCorrelationThreshold', 'runChannels', 'brain', 'muscle', 'eye', 'heart', 'line', 'channel', 'other','AutomaticrejectSwitch', 'fromSampling', 'toSampling'};
            
            valuesConf = {app.studyName.Value, app.dataprotocol.Value, app.singlesubject.Value, app.plotsave.Value, app.dataDir.Value, app.channelFile.Value, app.outputDir.Value, app.runSteps.Value, app.savepoint.Value, app.lineFrequencies.Value, ...
                    app.lowpass.Value, app.highpass.Value,app.notchlow.Value,app.notchhigh.Value,app.revfilt.Value, app.detrendCutoff.Value, app.detrendStepSize.Value, app.robustDeviationThreshold.Value, ...
                    app.correlationThreshold.Value, app.badTimeThreshold.Value, app.highFrequencyNoiseThreshold.Value, app.ransacCorrelationThreshold.Value, app.runChannels.Value,  app.brain.Value, app.muscle.Value, app.eye.Value, app.heart.Value, app.line.Value, app.channel.Value, app.other.Value, app.AutomaticrejectSwitch.Value, app.fromSampling.Value, app.toSampling.Value};
            
            RunPipelineConfiguration = containers.Map(keysConf, valuesConf);            
            
            run(fullfile(fileparts(mfilename("fullpath")), "tech2Pipeline", "tech2.m"));
        end

        % Value changed function: ResetValuesButton
        function ResetValuesButtonValueChanged(app, event)
            % TODO: Change to .mat
            run(fullfile(fileparts(mfilename("fullpath")), "tech2Pipeline", "configuration.m"));
            
            for k = keys(RunPipelineConfiguration)
                try 
                  disp(RunPipelineConfiguration(k{1}));
                  actionPerf = strjoin(["app.",string(k),".Value=",RunPipelineConfiguration(k{1}),";"]);
                  if ischar(RunPipelineConfiguration(k{1}))
                     actionPerf = strjoin(["app.",string(k),".Value='",RunPipelineConfiguration(k{1}),"';"]);
                  end
                  disp(RunPipelineConfiguration(k{1}));
                  actionPerfNoSpaces = regexprep(actionPerf,' +','');
                  disp(actionPerfNoSpaces);
                  eval(actionPerfNoSpaces);
                catch me
                  disp(me)
                end
            end
        end

        % Value changed function: numChannels
        function numChannelsValueChanged(app, event)
            value = string([1:app.numChannels.Value]);
            app.runChannels.ItemsData = value;
            app.runChannels.Items = value;
            app.runChannels.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunApp, true);
            app.RunButton.Text = 'Run';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.StudyNameEditFieldLabel = uilabel(app.BasicTab);
            app.StudyNameEditFieldLabel.Text = 'Study Name';
            app.studyName = uieditfield(app.BasicTab, 'text');
            app.SaveStagesLabel = uilabel(app.BasicTab);
            app.SaveStagesLabel.Text = 'Save Stages';
            app.savepoint = uilistbox(app.BasicTab);
            app.savepoint.Items = {'1 BIDS Data', '2 HLPF', '3 PREP', '4 ICA', '5 IClabel', '6 Clean Label'};
            app.savepoint.Multiselect = 'on';
            app.savepoint.Value = {'1 BIDS Data', '2 HLPF', '3 PREP', '4 ICA', '5 IClabel', '6 Clean Label'};
            app.OutputdirectoryButton = uibutton(app.BasicTab, 'push');
            app.OutputdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OutputdirectoryButtonPushed, true);
            app.OutputdirectoryButton.Text = 'Output directory';
            app.DatadirectoryButton = uibutton(app.BasicTab, 'push');
            app.DatadirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DatadirectoryButtonPushed, true);
            app.DatadirectoryButton.Text = 'Data directory';
            app.dataDir = uieditfield(app.BasicTab, 'text');
            app.outputDir = uieditfield(app.BasicTab, 'text');
            app.DataprotocolDropDownLabel = uilabel(app.BasicTab);
            app.DataprotocolDropDownLabel.Text = 'Data protocol';
            app.dataprotocol = uidropdown(app.BasicTab);
            app.dataprotocol.Items = {'brainVision', 'EEGLab set', 'emotive'};
            app.dataprotocol.Value = 'brainVision';
            app.RunStagesLabel = uilabel(app.BasicTab);
            app.RunStagesLabel.Text = 'Run Stages ';
            app.runSteps = uilistbox(app.BasicTab);
            app.runSteps.Items = {'1 BIDS Data', '2 HLPF', '3 PREP', '4 ICA', '5 IClabel', '6 Clean Label'};
            app.runSteps.Multiselect = 'on';
            app.runSteps.Value = {'1 BIDS Data', '2 HLPF', '3 PREP', '4 ICA', '5 IClabel', '6 Clean Label'};
            app.PlotStagesLabel = uilabel(app.BasicTab);
            app.PlotStagesLabel.Text = 'Plot Stages';
            app.plotsave = uilistbox(app.BasicTab);
            app.plotsave.Items = {'1 BIDS Data', '2 HLPF', '3 PREP', '4 ICA', '5 IClabel', '6 Clean Label'};
            app.plotsave.ItemsData = {'1 BIDS Data', '2 HLPF', '3 PREP', '4 ICA', '5 IClabel', '6 Clean Label'};
            app.plotsave.Multiselect = 'on';
            app.plotsave.Value = {'1 BIDS Data', '2 HLPF', '3 PREP', '4 ICA', '5 IClabel', '6 Clean Label'};
            app.IfDatasamplingrateisaboveLabel = uilabel(app.BasicTab);
            app.IfDatasamplingrateisaboveLabel.Text = 'If Data sampling rate is above:';
            app.fromSampling = uieditfield(app.BasicTab, 'numeric');
            app.downsampletoLabel = uilabel(app.BasicTab);
            app.downsampletoLabel.Text = 'downsample to:';
            app.toSampling = uieditfield(app.BasicTab, 'numeric');
            app.AutomaticrejectSwitch = uiswitch(app.BasicTab, 'slider');
            app.AutomaticrejectSwitchLabel = uilabel(app.BasicTab);
            app.AutomaticrejectSwitchLabel.Text = 'Automatic reject bad sectors';
            app.WARNINGmanipulatingindividualsubjectdatadurationLabel = uilabel(app.BasicTab);
            app.WARNINGmanipulatingindividualsubjectdatadurationLabel.Text = {'WARNING: manipulating individual subject'; 'data duration.'};
            app.singlesubject = uiswitch(app.BasicTab, 'slider');
            app.TestsinglesubjectLabel = uilabel(app.BasicTab);
            app.TestsinglesubjectLabel.Text = 'Test single subject';
            app.ChannelsTab = uitab(app.TabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.runChannels = uilistbox(app.ChannelsTab);
            app.runChannels.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', ''};
            app.runChannels.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', ''};
            app.runChannels.Multiselect = 'on';
            app.runChannels.Value = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.NumChannelsLabel = uilabel(app.ChannelsTab);
            app.NumChannelsLabel.Text = 'Num Channels';
            app.numChannels = uislider(app.ChannelsTab);
            app.numChannels.Limits = [16 128];
            app.numChannels.MajorTicks = [16 32 64 128];
            app.numChannels.ValueChangedFcn = createCallbackFcn(app, @numChannelsValueChanged, true);
            app.numChannels.MinorTicks = [16 32 64 128];
            app.numChannels.Value = 16;
            app.RunChannelsLabel = uilabel(app.ChannelsTab);
            app.RunChannelsLabel.Text = 'Run Channels';
            app.ChannelfileButton = uibutton(app.ChannelsTab, 'push');
            app.ChannelfileButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelfileButtonPushed, true);
            app.ChannelfileButton.Text = 'Channel file';
            app.channelFile = uieditfield(app.ChannelsTab, 'text');
            app.PrepTab = uitab(app.TabGroup);
            app.PrepTab.Title = 'Prep';
            app.DetrendCutoffHzSpinnerLabel = uilabel(app.PrepTab);
            app.DetrendCutoffHzSpinnerLabel.Text = 'Detrend Cutoff (Hz)';
            app.detrendCutoff = uispinner(app.PrepTab);
            app.detrendCutoff.Step = 0.5;
            app.detrendCutoff.Limits = [0 Inf];
            app.detrendCutoff.Value = 1;
            app.DetrendStepSizeSpinnerLabel = uilabel(app.PrepTab);
            app.DetrendStepSizeSpinnerLabel.Text = 'Detrend Step Size';
            app.detrendStepSize = uispinner(app.PrepTab);
            app.detrendStepSize.Step = 0.01;
            app.detrendStepSize.Limits = [0 Inf];
            app.detrendStepSize.Value = 0.02;
            app.LinefrequenciesmultiplechoiceListBoxLabel = uilabel(app.PrepTab);
            app.LinefrequenciesmultiplechoiceListBoxLabel.Text = {'Line frequencies:'; '(multiple choice)'};
            app.lineFrequencies = uilistbox(app.PrepTab);
            app.lineFrequencies.Items = {'[50  100  150  200]', '[60  120  180  240]'};
            app.lineFrequencies.Multiselect = 'on';
            app.lineFrequencies.Value = {'[50  100  150  200]'};
            app.EUILLabel = uilabel(app.PrepTab);
            app.EUILLabel.Text = 'EU/IL';
            app.USLabel = uilabel(app.PrepTab);
            app.USLabel.Text = 'US ';
            app.HighpassFilterHzSpinnerLabel = uilabel(app.PrepTab);
            app.HighpassFilterHzSpinnerLabel.Text = 'Highpass Filter (Hz)';
            app.highpass = uispinner(app.PrepTab);
            app.highpass.Step = 0.5;
            app.highpass.Limits = [0 Inf];
            app.highpass.Value = 1;
            app.LowpassFilterHzSpinnerLabel = uilabel(app.PrepTab);
            app.LowpassFilterHzSpinnerLabel.Text = 'Lowpass Filter (Hz)';
            app.lowpass = uispinner(app.PrepTab);
            app.lowpass.Step = 0.5;
            app.lowpass.Limits = [0 Inf];
            app.lowpass.Value = 1;
            app.NotchLowFilterHzLabel = uilabel(app.PrepTab);
            app.NotchLowFilterHzLabel.Text = 'Notch Low Filter (Hz)';
            app.notchlow = uispinner(app.PrepTab);
            app.notchlow.Step = 0.5;
            app.notchlow.Limits = [0 Inf];
            app.notchlow.Value = 1;
            app.HighpassHighHzLabel = uilabel(app.PrepTab);
            app.HighpassHighHzLabel.Text = 'Highpass High (Hz)';
            app.notchhigh = uispinner(app.PrepTab);
            app.notchhigh.Step = 0.5;
            app.notchhigh.Limits = [0 Inf];
            app.notchhigh.Value = 1;
            app.revfiltLabel = uilabel(app.PrepTab);
            app.revfiltLabel.Text = 'revfilt';
            app.revfilt = uispinner(app.PrepTab);
            app.revfilt.Step = 0.5;
            app.revfilt.Limits = [0 Inf];
            app.revfilt.Value = 1;
            app.ICATab = uitab(app.TabGroup);
            app.ICATab.Title = 'ICA';
            app.RobustDeviationThresholdSpinnerLabel = uilabel(app.ICATab);
            app.RobustDeviationThresholdSpinnerLabel.Text = 'Robust Deviation Threshold';
            app.robustDeviationThreshold = uispinner(app.ICATab);
            app.robustDeviationThreshold.Step = 0.5;
            app.robustDeviationThreshold.Limits = [0 Inf];
            app.robustDeviationThreshold.Value = 1;
            app.CorrelationThresholdSpinnerLabel = uilabel(app.ICATab);
            app.CorrelationThresholdSpinnerLabel.Text = 'Correlation Threshold';
            app.correlationThreshold = uispinner(app.ICATab);
            app.correlationThreshold.Step = 0.5;
            app.correlationThreshold.Limits = [0 Inf];
            app.correlationThreshold.Value = 1;
            app.BadTimeThresholdSpinnerLabel = uilabel(app.ICATab);
            app.BadTimeThresholdSpinnerLabel.Text = 'Bad Time Threshold';
            app.badTimeThreshold = uispinner(app.ICATab);
            app.badTimeThreshold.Step = 0.5;
            app.badTimeThreshold.Limits = [0 Inf];
            app.badTimeThreshold.Value = 1;
            app.HighFrequencyNoiseThresholdSpinnerLabel = uilabel(app.ICATab);
            app.HighFrequencyNoiseThresholdSpinnerLabel.Text = 'High Frequency Noise Threshold';
            app.highFrequencyNoiseThreshold = uispinner(app.ICATab);
            app.highFrequencyNoiseThreshold.Step = 0.5;
            app.highFrequencyNoiseThreshold.Limits = [0 Inf];
            app.highFrequencyNoiseThreshold.Value = 1;
            app.RansacCorrelationThresholdSpinnerLabel = uilabel(app.ICATab);
            app.RansacCorrelationThresholdSpinnerLabel.Text = 'Ransac Correlation Threshold';
            app.ransacCorrelationThreshold = uispinner(app.ICATab);
            app.ransacCorrelationThreshold.Step = 0.5;
            app.ransacCorrelationThreshold.Limits = [0 Inf];
            app.ransacCorrelationThreshold.Value = 1;
            app.ICLabelTab = uitab(app.TabGroup);
            app.ICLabelTab.Title = 'ICLabel';
            app.BrainSliderLabel = uilabel(app.ICLabelTab);
            app.BrainSliderLabel.Text = 'Brain';
            app.brain = uislider(app.ICLabelTab);
            app.brain.Limits = [0 1];
            app.brain.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.brain.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.muscle = uislider(app.ICLabelTab);
            app.muscle.Limits = [0 1];
            app.muscle.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.muscle.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.eye = uislider(app.ICLabelTab);
            app.eye.Limits = [0 1];
            app.eye.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.eye.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.eye.Value = 0.9;
            app.heart = uislider(app.ICLabelTab);
            app.heart.Limits = [0 1];
            app.heart.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.heart.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.line = uislider(app.ICLabelTab);
            app.line.Limits = [0 1];
            app.line.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.line.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.channel = uislider(app.ICLabelTab);
            app.channel.Limits = [0 1];
            app.channel.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.channel.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.other = uislider(app.ICLabelTab);
            app.other.Limits = [0 1];
            app.other.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.other.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MuscleLabel = uilabel(app.ICLabelTab);
            app.MuscleLabel.Text = 'Muscle';
            app.EyeLabel = uilabel(app.ICLabelTab);
            app.EyeLabel.Text = 'Eye';
            app.HeartLabel = uilabel(app.ICLabelTab);
            app.HeartLabel.Text = 'Heart';
            app.LineLabel = uilabel(app.ICLabelTab);
            app.LineLabel.Text = 'Line';
            app.ChannelLabel = uilabel(app.ICLabelTab);
            app.ChannelLabel.Text = 'Channel ';
            app.OtherLabel = uilabel(app.ICLabelTab);
            app.OtherLabel.Text = 'Other';
            app.ResetValuesButton = uibutton(app.UIFigure, 'state');
            app.ResetValuesButton.ValueChangedFcn = createCallbackFcn(app, @ResetValuesButtonValueChanged, true);
            app.ResetValuesButton.Text = 'Reset Values';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'tech.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end